classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AdvancedOperationLabel      matlab.ui.control.Label
        BasicOperationLabel         matlab.ui.control.Label
        EdgeDetectionButton         matlab.ui.control.Button
        ComplementImageButton       matlab.ui.control.Button
        HistogramButton             matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        ConverttoBinaryImageButton  matlab.ui.control.Button
        RGBtoGrayButton             matlab.ui.control.Button
        UploadImageButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        OriginalImage
        CurrentImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
    
           [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp','Image Files'});
  
           if isequal(file,0)
             return;
           end
           img = imread(fullfile(path, file));
    
          app.OriginalImage = img; 
          app.CurrentImage = img;
    
    
         imshow(app.CurrentImage, 'Parent', app.UIAxes);
         title(app.UIAxes, 'Original Image');
         axis(app.UIAxes, 'off'); 
        end

        % Button pushed function: RGBtoGrayButton
        function RGBtoGrayButtonPushed(app, event)
           
            if isempty(app.CurrentImage)
               uialert(app.UIFigure, 'Please upload an image first.', 'Error');
               return;
            end
           img = app.CurrentImage;
           if ndims(img) == 3
              grayImg = rgb2gray(img);
              app.CurrentImage = grayImg;
              imshow(grayImg, 'Parent', app.UIAxes);
              title(app.UIAxes, 'Gray Image');
              axis(app.UIAxes, 'off');
    
          elseif islogical(img)
                 uialert(app.UIFigure, 'The image is already binary (cannot convert to gray).', 'Notice');
           else
                uialert(app.UIFigure, 'The image is already grayscale.', 'Notice');
            end
        end

        % Button pushed function: ConverttoBinaryImageButton
        function ConverttoBinaryImageButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, 'Please upload an image first.', 'Error');
                return;
            end
            if ndims(img) == 3
                img = rgb2gray(im2double(img));
            else
                img = im2double(img);
            end

            try
                level = graythresh(img);
            catch
                level = mean(img(:));
            end

            bwImg = img > level;

            app.CurrentImage = bwImg;
            imshow(bwImg, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Binary Image');
            axis(app.UIAxes, 'off');

        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            % Button pushed function: RESETButton
  
            if isempty(app.OriginalImage)
               uialert(app.UIFigure, 'No image uploaded yet.', 'Error');
               return;
            end

            if isequal(app.CurrentImage, app.OriginalImage)
               uialert(app.UIFigure, 'The image is already the original one.', 'Notice');
               return;
            end
            app.CurrentImage = app.OriginalImage;
           imshow(app.CurrentImage, 'Parent', app.UIAxes);
           title(app.UIAxes, 'Original Image');
           axis(app.UIAxes, 'off');

        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            img = app.CurrentImage;

            figure('Name','All Histograms',...
                'Color','k','NumberTitle','off',...
                'Position',[200 100 1000 800]);

            if ndims(img) == 3
                R = double(img(:,:,1));
                G = double(img(:,:,2));
                B = double(img(:,:,3));
                bins = 0:255;

                
                subplot(2,1,1);
                hold on;
                bar(bins(1:end-1), histcounts(R(:)*255,bins), 'FaceColor', 'r', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                bar(bins(1:end-1), histcounts(G(:)*255,bins), 'FaceColor', 'g', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                bar(bins(1:end-1), histcounts(B(:)*255,bins), 'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                hold off;
                title('Histogram of RGB Image','Color','w','FontWeight','bold','FontSize',12);
                xlabel('Intensity','Color','w');
                ylabel('Count','Color','w');
                legend({'Red','Green','Blue'},'TextColor','w','Box','off');
                set(gca,'Color','k','XColor','w','YColor','w');
                grid on;

             
                subplot(2,1,2);
                Rc = 1 - R; Gc = 1 - G; Bc = 1 - B;
                hold on;
                bar(bins(1:end-1), histcounts(Rc(:)*255,bins), 'FaceColor', 'r', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                bar(bins(1:end-1), histcounts(Gc(:)*255,bins), 'FaceColor', 'g', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                bar(bins(1:end-1), histcounts(Bc(:)*255,bins), 'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.4);
                hold off;
                title('Complement of RGB Image','Color','w','FontWeight','bold','FontSize',12);
                xlabel('Intensity','Color','w');
                ylabel('Count','Color','w');
                set(gca,'Color','k','XColor','w','YColor','w');
                grid on;

             
            elseif ismatrix(img) && ~islogical(img)
                if isfloat(img)
                    gray = uint8(img * 255);
                else
                    gray = img;
                end
                bins = 0:255;

                subplot(2,1,1);
                counts = histcounts(gray(:), bins);
                bar(bins(1:end-1), counts, 'FaceColor', [0.8 0.8 0.8], 'EdgeColor', 'none');
                title('Histogram of Grayscale Image', 'Color', 'w', 'FontWeight', 'bold');
                xlabel('Intensity','Color','w');
                ylabel('Count','Color','w');
                set(gca,'Color','k','XColor','w','YColor','w');
                grid on;

               
                subplot(2,1,2);
                comp = imcomplement(gray);
                countsC = histcounts(comp(:), bins);
                bar(bins(1:end-1), countsC, 'FaceColor', [0.4 0.4 0.4], 'EdgeColor', 'none');
                title('Complement of Grayscale Image', 'Color', 'w', 'FontWeight', 'bold');
                xlabel('Intensity','Color','w');
                ylabel('Count','Color','w');
                set(gca,'Color','k','XColor','w','YColor','w');
                grid on;

               
            elseif islogical(img)
                bins = [0 1];
                counts0 = sum(img(:) == 0);
                counts1 = sum(img(:) == 1);

               
                subplot(2,1,1);
                bar(bins, [counts0 counts1], 0.5, 'FaceColor', [0.7 0.7 0.7], ...
                    'EdgeColor', 'w', 'LineWidth', 1.2);
                xlim([-0.5 1.5]);
                ylim([0 max([counts0 counts1])*1.2]);
                title('Histogram of Edges/Binary Image', 'Color', 'w', ...
                    'FontWeight', 'bold', 'FontSize', 12);
                xlabel('Pixel Value (0=Black, 1=White)', 'Color', 'w');
                ylabel('Count', 'Color', 'w');
                set(gca, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'XTick', [0 1]);
                grid on;

                
                subplot(2,1,2);
                bar(bins, [counts1 counts0], 0.5, 'FaceColor', [0.3 0.3 0.3], ...
                    'EdgeColor', 'w', 'LineWidth', 1.2);
                xlim([-0.5 1.5]);
                ylim([0 max([counts0 counts1])*1.2]);
                title('Complement of Edges/Binary Image', 'Color', 'w', ...
                    'FontWeight', 'bold', 'FontSize', 12);
                xlabel('Pixel Value (0↔1)', 'Color', 'w');
                ylabel('Count', 'Color', 'w');
                set(gca, 'Color', 'k', 'XColor', 'w', 'YColor', 'w', 'XTick', [0 1]);
                grid on;
            end


        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, 'Please upload an image first.', 'Error');
                return;
            end

            img = app.CurrentImage;
            if ndims(img) == 3
                grayImg = 0.2989 * double(img(:,:,1)) + ...
                    0.5870 * double(img(:,:,2)) + ...
                    0.1140 * double(img(:,:,3));
                titleText = 'Edge Detection (from RGB image)';
            else
                grayImg = double(img);
                titleText = 'Edge Detection (from Grayscale/Binary image)';
            end
            sobelX = [-1 0 1; -2 0 2; -1 0 1];
            sobelY = [-1 -2 -1; 0 0 0; 1 2 1];
            Gx = conv2(grayImg, sobelX, 'same');
            Gy = conv2(grayImg, sobelY, 'same');
            edgeImg = sqrt(Gx.^2 + Gy.^2);
            edgeImg = edgeImg ./ max(edgeImg(:));
            app.CurrentImage = edgeImg;
            imshow(edgeImg, 'Parent', app.UIAxes);
            title(app.UIAxes, titleText);
            axis(app.UIAxes, 'off');

        end

        % Button pushed function: ComplementImageButton
        function ComplementImageButtonPushed(app, event)
          
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, 'Please upload an image first.', 'Error');
                return;
            end

            img = double(app.CurrentImage); 
            if ndims(img) == 3
                compImg = 1 - (img ./ 255);  
                titleText = 'Complement of RGB Image';
            else
                
                if max(img(:)) > 1
                    compImg = 1 - (img ./ 255);
                else
                    compImg = 1 - img;
                end
                titleText = 'Complement of Grayscale/Binary Image';
            end

            app.CurrentImage = compImg;
            imshow(compImg, 'Parent', app.UIAxes);
            title(app.UIAxes, titleText);
            axis(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.RGBtoGrayButton = uibutton(app.UIFigure, 'push');
            app.RGBtoGrayButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGrayButtonPushed, true);
            app.RGBtoGrayButton.Text = 'RGB to Gray ';
            app.ConverttoBinaryImageButton = uibutton(app.UIFigure, 'push');
            app.ConverttoBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoBinaryImageButtonPushed, true);
            app.ConverttoBinaryImageButton.Text = 'Convert to Binary Image';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET ';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.ComplementImageButton = uibutton(app.UIFigure, 'push');
            app.ComplementImageButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementImageButtonPushed, true);
            app.ComplementImageButton.Text = 'Complement Image';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection ';
            app.BasicOperationLabel = uilabel(app.UIFigure);
            app.BasicOperationLabel.Text = 'Basic Operation ';
            app.AdvancedOperationLabel = uilabel(app.UIFigure);
            app.AdvancedOperationLabel.Text = 'Advanced Operation ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end